classdef Mic_Check_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        Label_3                 matlab.ui.control.Label
        BioinspiredScienceTechnologyResearchLabel  matlab.ui.control.Label
        UBDVTLaboratoryLabel    matlab.ui.control.Label
        Image2                  matlab.ui.control.Image
        Image                   matlab.ui.control.Image
        TextArea                matlab.ui.control.TextArea
        TimeSpanEditField       matlab.ui.control.NumericEditField
        TimeSpanEditFieldLabel  matlab.ui.control.Label
        NextDataSetButton       matlab.ui.control.Button
        DataVisualizerLabel     matlab.ui.control.Label
        Label                   matlab.ui.control.Label
        Label_2                 matlab.ui.control.Label
        UIAxes_4                matlab.ui.control.UIAxes
        UIAxes_8                matlab.ui.control.UIAxes
        UIAxes_7                matlab.ui.control.UIAxes
        UIAxes_6                matlab.ui.control.UIAxes
        UIAxes_5                matlab.ui.control.UIAxes
        UIAxes_3                matlab.ui.control.UIAxes
        UIAxes_2                matlab.ui.control.UIAxes
    end

    properties (Access = public)
        %{
            Properties for this helper app. This is used for the mic_check
            functionality in the control pannel app.

            Sam Kramer
            June 16th, 2023
        %}

        % --Initialize main app variable
            main_app;
    
        % --Initialize data for plotting
            test_data;

        % --Initialize ticker button
            ticker;

        % --Initialize time span
            t_span;

    end

%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% Methods %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

    methods (Access = public)
        
        function plotdatafigures(app,data,ticker,t_span)
            %{
                This function will plot the data onto the helper app
                figures.

                Sam Kramer
                21 June, 2023
            %}

                % --Formating data
                    try
                        % --Select time span of data
                            time = data(:,1);
                            fs = 1/(time(10) - time(9));
                            index = round(fs*t_span);
                            data = data(1:index,2:width(data)); %Exclude time col.
                            time = time(1:length(data));
        
                    catch
                        % --Entire Time Span of Data if t_span is too large
                            time = data(:,1);
                            fs = 1/(time(10) - time(9));
                            index = round(length(time));
                            data = data(1:index,2:width(data)); %Exclude time col.
                            time = time(1:length(data));
                    end
    
                % --Selecting data and plotting it
                    data(:, 2 + (ticker - 1)*8) = data(:, 2 + (ticker - 1)*8) - mean(data(:, 2 + (ticker - 1)*8));
                    filtered_data(:, 2 + (ticker - 1)*8) = filter_data(data(:, 2 + (ticker - 1)*8),fs);
                    plot(app.UIAxes_2, time, filtered_data(:, 2 + (ticker - 1)*8))
                        title = "Mic " + string(2 + (ticker - 1)*8);
                        app.UIAxes_2.Title.String = title;

                    data(:, 3 + (ticker - 1)*8) = data(:, 3 + (ticker - 1)*8) - mean(data(:, 3 + (ticker - 1)*8));
                    plot(app.UIAxes_3,time,data(:,3 + (ticker - 1)*8))
                        title = "Mic " + string(3 + (ticker - 1)*8);
                        app.UIAxes_3.Title.String = title;

                    data(:, 4 + (ticker - 1)*8) = data(:, 4 + (ticker - 1)*8) - mean(data(:, 4 + (ticker - 1)*8));
                    plot(app.UIAxes_4,time,data(:,4 + (ticker - 1)*8))
                        title = "Mic " + string(4 + (ticker - 1)*8);
                        app.UIAxes_4.Title.String = title;

                    data(:, 5 + (ticker - 1)*8) = data(:, 5 + (ticker - 1)*8) - mean(data(:, 5 + (ticker - 1)*8));
                    plot(app.UIAxes_5,time,data(:,5 + (ticker - 1)*8))
                        title = "Mic " + string(5 + (ticker - 1)*8);
                        app.UIAxes_5.Title.String = title;

                    data(:, 6 + (ticker - 1)*8) = data(:, 6 + (ticker - 1)*8) - mean(data(:, 6 + (ticker - 1)*8));
                    plot(app.UIAxes_6,time,data(:,6 + (ticker - 1)*8))
                        title = "Mic " + string(6 + (ticker - 1)*8);
                        app.UIAxes_6.Title.String = title;

                    data(:, 7 + (ticker - 1)*8) = data(:, 7 + (ticker - 1)*8) - mean(data(:, 7 + (ticker - 1)*8));
                    plot(app.UIAxes_7,time,data(:,7 + (ticker - 1)*8))
                        title = "Mic " + string(7 + (ticker - 1)*8);
                        app.UIAxes_7.Title.String = title;

                    data(:, 8 + (ticker - 1)*8) = data(:, 8 + (ticker - 1)*8) - mean(data(:, 8 + (ticker - 1)*8));
                    plot(app.UIAxes_8,time,data(:,8 + (ticker - 1)*8))
                        title = "Mic " + string(8 + (ticker - 1)*8);
                        app.UIAxes_8.Title.String = title;
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, caller, raw_data)
            %{
                The start up function for the helper mic_check app for
                visualizing the data to the user.

                Sam Kramer
                June 16th, 2023
            %}

            % --Storing main app in this app as app.main_app
                app.main_app = caller;

            % --Storing raw data
                app.test_data = raw_data;

            % --Setup graphs
                app.ticker = 1;
                app.t_span = 0.5;
                app.TextArea.Value = string(app.ticker);

            % --Setup graphs
                app.UIAxes_2.XGrid = "on";
                app.UIAxes_2.YGrid = "on";

                app.UIAxes_3.XGrid = "on";
                app.UIAxes_3.YGrid = "on";
    
                app.UIAxes_4.XGrid = "on";
                app.UIAxes_4.YGrid = "on";

                app.UIAxes_5.XGrid = "on";
                app.UIAxes_5.YGrid = "on";

                app.UIAxes_6.XGrid = "on";
                app.UIAxes_6.YGrid = "on";
    
                app.UIAxes_7.XGrid = "on";
                app.UIAxes_7.YGrid = "on";

                app.UIAxes_8.XGrid = "on";
                app.UIAxes_8.YGrid = "on";

            % --Plot data on the graphs
                assignin('base',"raw_data",raw_data)
                plotdatafigures(app,raw_data,app.ticker,app.t_span)


        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            %{
                Properly closes the app when the user is done viewing the
                data.

                Sam Kramer
                June 16th, 2023
            %}
           
            % --Delete app
                delete(app)
        end

        % Button pushed function: NextDataSetButton
        function NextDataSetButtonPushed(app, event)
            %{
                This is the function that allows the user to click through
                all the data sets to see what microphones are not working
                and exclude the mics that do not work. This will simply
                tick through an integer and call an updator function.

                Sam Kramer
                June 16, 2023
            %}

            % --Cycle ticker
                if app.ticker == 4

                    app.ticker = 1;

                else

                    app.ticker = app.ticker + 1;

                end
                
            % --Display on app
                app.TextArea.Value = string(app.ticker);

            % --Update Graphs
                plotdatafigures(app,app.test_data,app.ticker,app.t_span)
        end

        % Value changed function: TimeSpanEditField
        function TimeSpanEditFieldValueChanged(app, event)
           %{
                This changes the time history length of the data.

                Sam Kramer
                21 June, 2023
           %}
            
            % --Update time span
                app.t_span = app.TimeSpanEditField.Value;

            % --Update graphs
                plotdatafigures(app,app.test_data,app.ticker,app.t_span)
            
        end

        % Value changed function: TextArea
        function TextAreaValueChanged(app, event)
            %{
                The page ticker read out for the user.

                Sam Kramer
                21 June, 2023
            %}
            
            app.TextArea.Value = string(app.ticker);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Title')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_3 = uiaxes(app.UIFigure);
            title(app.UIAxes_3, 'Title')
            zlabel(app.UIAxes_3, 'Z')
            app.UIAxes_5 = uiaxes(app.UIFigure);
            title(app.UIAxes_5, 'Title')
            xlabel(app.UIAxes_5, 'Time (s)')
            zlabel(app.UIAxes_5, 'Z')
            app.UIAxes_6 = uiaxes(app.UIFigure);
            title(app.UIAxes_6, 'Title')
            zlabel(app.UIAxes_6, 'Z')
            app.UIAxes_7 = uiaxes(app.UIFigure);
            title(app.UIAxes_7, 'Title')
            zlabel(app.UIAxes_7, 'Z')
            app.UIAxes_8 = uiaxes(app.UIFigure);
            title(app.UIAxes_8, 'Title')
            zlabel(app.UIAxes_8, 'Z')
            app.UIAxes_4 = uiaxes(app.UIFigure);
            title(app.UIAxes_4, 'Title')
            zlabel(app.UIAxes_4, 'Z')
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.DataVisualizerLabel = uilabel(app.UIFigure);
            app.DataVisualizerLabel.Text = 'Data Visualizer';
            app.NextDataSetButton = uibutton(app.UIFigure, 'push');
            app.NextDataSetButton.ButtonPushedFcn = createCallbackFcn(app, @NextDataSetButtonPushed, true);
            app.NextDataSetButton.Text = 'Next Data Set';
            app.TimeSpanEditFieldLabel = uilabel(app.UIFigure);
            app.TimeSpanEditFieldLabel.Text = 'Time Span';
            app.TimeSpanEditField = uieditfield(app.UIFigure, 'numeric');
            app.TimeSpanEditField.Limits = [0.001 20];
            app.TimeSpanEditField.ValueChangedFcn = createCallbackFcn(app, @TimeSpanEditFieldValueChanged, true);
            app.TimeSpanEditField.Value = 0.5;
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.ValueChangedFcn = createCallbackFcn(app, @TextAreaValueChanged, true);
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'UBD logo original.png');
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'Vertical_VT_Full_Color_RGB.png');
            app.UBDVTLaboratoryLabel = uilabel(app.UIFigure);
            app.UBDVTLaboratoryLabel.Text = 'UBD - VT Laboratory';
            app.BioinspiredScienceTechnologyResearchLabel = uilabel(app.UIFigure);
            app.BioinspiredScienceTechnologyResearchLabel.Text = 'Bioinspired Science & Technology Research';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '2023';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Mic_Check_App(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end