classdef Visualize_Output < matlab.apps.AppBase
    %VISUALIZE_OUTPUT
    %   This app is a helper app that is used to visualize the output of
    %   the signal that was sent to the cameras to trigger them. This app
    %   is very simple and allows for a single running instance of it when
    %   called.
    %   
    %   Sam Kramer
    %   July 13th, 2023
    %   
    %   See also Mic_Check_App, and Array_Control_App

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        VisualizeTriggerSignalLabel  matlab.ui.control.Label
        Label_3                      matlab.ui.control.Label
        Label_2                      matlab.ui.control.Label
        Image3                       matlab.ui.control.Image
        Label                        matlab.ui.control.Label
        BioinspiredScienceTechnologyResearchLabel  matlab.ui.control.Label
        UBDVTLaboratoryLabel         matlab.ui.control.Label
        Image2                       matlab.ui.control.Image
        Label_4                      matlab.ui.control.Label
        UIAxes                       matlab.ui.control.UIAxes
    end

    
    properties (Access = public)

        % --List all public properties for the app
            trigger_signal;
            main_app;

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, caller, time_span, frequency, voltage, pause)
            %{
                This is the start up function that will be used to show the
                user the output of the signal that was sent to trigger the
                array. This will only plot the output signal and that is
                all the app is meant to do.

                Sam Kramer
                July 13th, 2023
            %}

            % --Call mainapp
                app.main_app = caller;

            % --Create signal
                x = 1:(time_span + pause)*frequency;
                app.trigger_signal = voltage.*(heaviside(x - pause*frequency))';
                app.trigger_signal = [app.trigger_signal; zeros(2,1)];
                time = 0:(1/frequency):(time_span + pause + (1/frequency));

            % --Plot signal
                plot(app.UIAxes, time, app.trigger_signal)
                    xlabel(app.UIAxes,"Time (s)")
                    ylabel(app.UIAxes,"Signal Amplitude (V)")
                    title(app.UIAxes,"Trigger Signal")
                    app.UIAxes.XGrid = 'on';
                    app.UIAxes.YGrid = 'on';
                    
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '';
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'UBD logo original.png');
            app.UBDVTLaboratoryLabel = uilabel(app.UIFigure);
            app.UBDVTLaboratoryLabel.Text = 'UBD - VT Laboratory';
            app.BioinspiredScienceTechnologyResearchLabel = uilabel(app.UIFigure);
            app.BioinspiredScienceTechnologyResearchLabel.Text = 'Bioinspired Science & Technology Research';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '2023';
            app.Image3 = uiimage(app.UIFigure);
            app.Image3.ImageSource = fullfile(pathToMLAPP, 'Vertical_VT_Full_Color_RGB.png');
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '';
            app.VisualizeTriggerSignalLabel = uilabel(app.UIFigure);
            app.VisualizeTriggerSignalLabel.Text = 'Visualize Trigger Signal';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Visualize_Output(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end